classdef SwingDAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DisconnectButton            matlab.ui.control.Button
        BrowseButton                matlab.ui.control.Button
        FilenametosaveacquireddataEditField  matlab.ui.control.EditField
        FilenametosaveacquireddataEditFieldLabel  matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        EndDAQButton                matlab.ui.control.Button
        StartDAQButton              matlab.ui.control.Button
        COMPortforArduinoEditField  matlab.ui.control.EditField
        COMPortforArduinoEditFieldLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        myArduino % serial port connected to Arduino
        acqDataBlk % object to trigger event when a block of data has been 
                   % acquired through the serial port
    end

    properties (Constant, Access = public)
        plotBlockSize = 10;
    end

    events
        AcquiredDataBlock
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartDAQButton
        function StartDAQButtonPushed(app, event)
            app.myArduino.UserData = struct("Data",[],"Count",0);
            configureCallback(app.myArduino,"terminator",...
                            @(src,evt)(readArduino(src,evt,app)));
        end

        % Button pushed function: EndDAQButton
        function EndDAQButtonPushed(app, event)
            configureCallback(app.myArduino, "off");
            acquiredData = app.myArduino.UserData.Data;
            filename = app.FilenametosaveacquireddataEditField.Value;
            save(filename,"acquiredData");
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            comport = app.COMPortforArduinoEditField.Value;
            app.myArduino = serialport(comport, 57600);
            configureTerminator(app.myArduino,"CR/LF");

            addlistener(app,'AcquiredDataBlock',...
                @(src,evt)plotAcquiredData(src,evt,...
                                           app.myArduino,app.UIAxes));
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path] = uiputfile("*.mat");
            app.FilenametosaveacquireddataEditField.Value = ...
                fullfile(path,file);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.myArduino.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Acquired Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.COMPortforArduinoEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortforArduinoEditFieldLabel.Text = 'COM Port for Arduino';
            app.COMPortforArduinoEditField = uieditfield(app.UIFigure, 'text');
            app.StartDAQButton = uibutton(app.UIFigure, 'push');
            app.StartDAQButton.ButtonPushedFcn = createCallbackFcn(app, @StartDAQButtonPushed, true);
            app.StartDAQButton.Text = 'Start DAQ';
            app.EndDAQButton = uibutton(app.UIFigure, 'push');
            app.EndDAQButton.ButtonPushedFcn = createCallbackFcn(app, @EndDAQButtonPushed, true);
            app.EndDAQButton.Text = 'End DAQ';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.FilenametosaveacquireddataEditFieldLabel = uilabel(app.UIFigure);
            app.FilenametosaveacquireddataEditFieldLabel.Text = 'Filename to  save acquired data';
            app.FilenametosaveacquireddataEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwingDAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end